classdef ce_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CommonEmitterCalculatorUIFigure  matlab.ui.Figure
        LoadResistanceEditField         matlab.ui.control.NumericEditField
        LoadResistanceEditFieldLabel    matlab.ui.control.Label
        OmegaLabel                      matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        SupplyVoltageEditField          matlab.ui.control.NumericEditField
        SupplyVoltageEditFieldLabel     matlab.ui.control.Label
        I_BLabel                        matlab.ui.control.Label
        DividerCurrentEditField         matlab.ui.control.NumericEditField
        DividerCurrentEditFieldLabel    matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        SaturationCurrentEditField      matlab.ui.control.NumericEditField
        SaturationCurrentEditFieldLabel  matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        EmitterVoltageEditField         matlab.ui.control.NumericEditField
        EmitterVoltageEditFieldLabel    matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        mVLabel                         matlab.ui.control.Label
        mALabel                         matlab.ui.control.Label
        OutputPanel                     matlab.ui.container.Panel
        ClickonCalculatetogetanoutputLabel  matlab.ui.control.Label
        CollectorCurrentEditField       matlab.ui.control.NumericEditField
        CollectorCurrentEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.SupplyVoltageEditField.Value = 0;
            app.CollectorCurrentEditField.Value = 0;
            app.EmitterVoltageEditField.Value = 0;
            app.BetaEditField.Value = 0;
            app.SaturationCurrentEditField.Value = 0;
            app.DividerCurrentEditField.Value = 0;
            app.LoadResistanceEditField.Value = 0;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SupplyVoltageEditField.Value = 12;
            app.CollectorCurrentEditField.Value = 1;
            app.EmitterVoltageEditField.Value = 200;
            app.BetaEditField.Value = 100;
            app.SaturationCurrentEditField.Value = 1e-17;
            app.DividerCurrentEditField.Value = 10;
            app.LoadResistanceEditField.Value = 1e4;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            vcc = app.SupplyVoltageEditField.Value;
            ic = app.CollectorCurrentEditField.Value*1e-3;
            ve = app.EmitterVoltageEditField.Value*1e-3;
            beta = app.BetaEditField.Value;
            is = app.SaturationCurrentEditField.Value;
            divm = app.DividerCurrentEditField.Value;
            rl = app.LoadResistanceEditField.Value;

            ib = ic/beta;
            ie = ib + ic;

            re = ve/ie;

            vbe = 26e-3*log(ic/is);
            vb = vbe + ve;

            r1 = (vcc-vb)/(divm*ib);
            r2 = vb*r1/(vcc-vb);

            rc = (vcc-vb)/ic;

            vc = vcc-ic*rc;

            rx = 1/(1/rl+1/rc);

            av = -rx/re;

            if(re<1e3)
            app.ClickonCalculatetogetanoutputLabel.Text = ...
            ['Currents:',...
             '$$I_B=',num2str(ib*1e6),'\quad\mu A$$',...
             '$$I_E=',num2str(ie*1e3), '\quad mA$$',...
             '$$ $$',...
             'Resistances:',...
             '$$R_1=',num2str(r1/1e3, "%.2f"),'\quad k\Omega$$',...
             '$$R_2=',num2str(r2/1e3, "%.2f"),'\quad k\Omega$$',...
             '$$R_E=',num2str(re, "%.2f"),'\quad \Omega$$',...
             '$$R_C<',num2str(rc/1e3, "%.2f"),'\quad k\Omega$$',...
             '$$ $$',...
             'Voltages:',...
             '$$V_C=',num2str(vc, "%.2f"),'\quad V$$',...
             '$$V_E=',num2str(ve*1e3, "%.2f"),'\quad mV$$',...
             '$$V_B=',num2str(vb, "%.2f"),'\quad V$$',...
             '$$V_{BE}=',num2str(vbe, "%.2f"),'\quad V$$',...
             '$$ $$',...
             '$$A_V\approx', num2str(av, "%.2f")...
             ];
            else
            app.ClickonCalculatetogetanoutputLabel.Text = ...
            ['Currents:',...
             '$$I_B=',num2str(ib*1e6),'\quad \mu A$$',...
             '$$I_E=',num2str(ie*1e3), '\quad mA$$',...
             '$$ $$',...
             'Resistances:',...
             '$$R_1=',num2str(r1/1e3, "%.2f"),'\quad k\Omega$$',...
             '$$R_2=',num2str(r2/1e3, "%.2f"),'\quad k\Omega$$',...
             '$$R_E=',num2str(re/1e3, "%.2f"),'\quad k\Omega$$',...
             '$$R_C<',num2str(rc/1e3, "%.2f"),'\quad k\Omega$$',...
             '$$ $$',...
             'Voltages:',...
             '$$V_C=',num2str(vc, "%.2f"),'\quad V$$',...
             '$$V_E=',num2str(ve*1e3, "%.2f"),'\quad mV$$',...
             '$$V_B=',num2str(vb, "%.2f"),'\quad V$$',...
             '$$V_{BE}=',num2str(vbe, "%.2f"),'\quad V$$',...
             '$$ $$',...
             '$$A_V\approx', num2str(av, "%.2f")...
             ];
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CommonEmitterCalculatorUIFigure = uifigure('Visible', 'off');
            app.CommonEmitterCalculatorUIFigure.Name = 'Common Emitter Calculator';
            app.CommonEmitterCalculatorUIFigure.Icon = '1200px-Transistor.symbol.npn.svg.png';
            app.CollectorCurrentEditFieldLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.CollectorCurrentEditFieldLabel.Text = 'Collector Current';
            app.CollectorCurrentEditField = uieditfield(app.CommonEmitterCalculatorUIFigure, 'numeric');
            app.CollectorCurrentEditField.Limits = [0 Inf];
            app.CollectorCurrentEditField.Value = 1;
            app.OutputPanel = uipanel(app.CommonEmitterCalculatorUIFigure);
            app.OutputPanel.Title = 'Output';
            app.ClickonCalculatetogetanoutputLabel = uilabel(app.OutputPanel);
            app.ClickonCalculatetogetanoutputLabel.Interpreter = 'latex';
            app.ClickonCalculatetogetanoutputLabel.Text = 'Click on "Calculate" to get an output.';
            app.mALabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.mALabel.Interpreter = 'latex';
            app.mALabel.Text = '$$mA$$';
            app.mVLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.mVLabel.Interpreter = 'latex';
            app.mVLabel.Text = '$$mV$$';
            app.ClearButton = uibutton(app.CommonEmitterCalculatorUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.CommonEmitterCalculatorUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CalculateButton = uibutton(app.CommonEmitterCalculatorUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EmitterVoltageEditFieldLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.EmitterVoltageEditFieldLabel.Text = 'Emitter Voltage';
            app.EmitterVoltageEditField = uieditfield(app.CommonEmitterCalculatorUIFigure, 'numeric');
            app.EmitterVoltageEditField.Limits = [0 Inf];
            app.EmitterVoltageEditField.Value = 200;
            app.BetaEditFieldLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.CommonEmitterCalculatorUIFigure, 'numeric');
            app.BetaEditField.Limits = [0 Inf];
            app.BetaEditField.Value = 100;
            app.SaturationCurrentEditFieldLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.SaturationCurrentEditFieldLabel.Text = 'Saturation Current';
            app.SaturationCurrentEditField = uieditfield(app.CommonEmitterCalculatorUIFigure, 'numeric');
            app.SaturationCurrentEditField.Limits = [0 Inf];
            app.SaturationCurrentEditField.Value = 1e-17;
            app.ALabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.ALabel.Interpreter = 'latex';
            app.ALabel.Text = '$$A$$';
            app.DividerCurrentEditFieldLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.DividerCurrentEditFieldLabel.Text = 'Divider Current';
            app.DividerCurrentEditField = uieditfield(app.CommonEmitterCalculatorUIFigure, 'numeric');
            app.DividerCurrentEditField.Limits = [0 Inf];
            app.DividerCurrentEditField.Value = 10;
            app.I_BLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.I_BLabel.Interpreter = 'latex';
            app.I_BLabel.Text = '$$I_B$$';
            app.SupplyVoltageEditFieldLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.SupplyVoltageEditFieldLabel.Text = 'Supply Voltage';
            app.SupplyVoltageEditField = uieditfield(app.CommonEmitterCalculatorUIFigure, 'numeric');
            app.SupplyVoltageEditField.Limits = [0 Inf];
            app.SupplyVoltageEditField.Value = 12;
            app.VLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.VLabel.Interpreter = 'latex';
            app.VLabel.Text = '$$V$$';
            app.OmegaLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.OmegaLabel.Interpreter = 'latex';
            app.OmegaLabel.Text = '$$\Omega$$';
            app.LoadResistanceEditFieldLabel = uilabel(app.CommonEmitterCalculatorUIFigure);
            app.LoadResistanceEditFieldLabel.Text = 'Load Resistance';
            app.LoadResistanceEditField = uieditfield(app.CommonEmitterCalculatorUIFigure, 'numeric');
            app.LoadResistanceEditField.Limits = [0 Inf];
            app.LoadResistanceEditField.Value = 10000;
            % Show the figure after all components are created
            app.CommonEmitterCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ce_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CommonEmitterCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CommonEmitterCalculatorUIFigure)
        end
    end
end